classdef PowerMeasurement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LaserPower                     matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        menu_about                     matlab.ui.container.Menu
        menu_debug                     matlab.ui.container.Menu
        menu_import                    matlab.ui.container.Menu
        menu_export                    matlab.ui.container.Menu
        menu_export_plot               matlab.ui.container.Menu
        button_init_closePowermeter    matlab.ui.control.Button
        button_init_micromanager       matlab.ui.control.Button
        text_legemd                    matlab.ui.control.TextArea
        LegendTextAreaLabel            matlab.ui.control.Label
        edit_measurement_averages      matlab.ui.control.NumericEditField
        AveragesEditFieldLabel         matlab.ui.control.Label
        button_measurement_stop        matlab.ui.control.Button
        button_measurement_start       matlab.ui.control.Button
        button_selectDir               matlab.ui.control.Button
        button_init_powermeter         matlab.ui.control.Button
        edit_init_portPowermeter       matlab.ui.control.NumericEditField
        COMPortPowermeterEditFieldLabel  matlab.ui.control.Label
        edit_measurement_stepsize      matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel         matlab.ui.control.Label
        dropdown_microscope            matlab.ui.control.DropDown
        MicroscopeDropDownLabel        matlab.ui.control.Label
        edit_outputDir                 matlab.ui.control.EditField
        OutputDirectoryEditFieldLabel  matlab.ui.control.Label
        axes_laser1                    matlab.ui.control.UIAxes
        axes_laser2                    matlab.ui.control.UIAxes
        axes_laser3                    matlab.ui.control.UIAxes
        axes_laser4                    matlab.ui.control.UIAxes
        axes_laser5                    matlab.ui.control.UIAxes
        axes_laser6                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
      
    end

    properties (Access = public)
      oldpath = addpath('.\Micromanager-Configs');
        oldpath2 = addpath('.\Micromanager')
        microscope;
        MM;
        mmc;

        stop % Description
        data; 
        powermeterDevice % Description
    end

    methods (Access = private)
       
        function updatePlot(app,laser,dichroic,laser_list_wavelengths)            
            if laser == 1
                cla(app.axes_laser1);
                for dic = 1:dichroic
                    plot(app.axes_laser1,app.data(:).Laser_Percentage_Power,1000*app.data(1).(char(laser_list_wavelengths(laser)))(dic,:));
                end
            elseif laser == 2
                cla(app.axes_laser2);
                for dic = 1:dichroic
                    plot(app.axes_laser2,app.data(:).Laser_Percentage_Power,1000*app.data(1).(char(laser_list_wavelengths(laser)))(dic,:));
                end 
            elseif laser == 3
                cla(app.axes_laser3);                
                for dic = 1:dichroic
                    plot(app.axes_laser3,app.data(:).Laser_Percentage_Power,1000*app.data(1).(char(laser_list_wavelengths(laser)))(dic,:));
                end 
            elseif laser == 4
                cla(app.axes_laser4);                
                for dic = 1:dichroic
                    plot(app.axes_laser4,app.data(:).Laser_Percentage_Power,1000*app.data(1).(char(laser_list_wavelengths(laser)))(dic,:));
                end 
            elseif laser == 5
                cla(app.axes_laser5);                
                for dic = 1:dichroic
                    plot(app.axes_laser5,app.data(:).Laser_Percentage_Power,1000*app.data(1).(char(laser_list_wavelengths(laser)))(dic,:));
                end 
            elseif laser == 6
                cla(app.axes_laser6);                
                for dic = 1:dichroic
                    plot(app.axes_laser6,app.data(:).Laser_Percentage_Power,1000*app.data(1).(char(laser_list_wavelengths(laser)))(dic,:));
                end 
            end
        end
        
        function updateMicroscope(app)
                clearAxis(app);
                config = getMicroscopeConfig(app, app.dropdown_microscope.Value);
                if isempty(config)
                    return
                end
             
                axesHandles = {app.axes_laser1, app.axes_laser2, app.axes_laser3, app.axes_laser4, app.axes_laser5, app.axes_laser6};
                numLasers = numel(config.wavelengths);
             
                for i = 1:numel(axesHandles)
                    if i <= numLasers
                        axesHandles{i}.Title.String = sprintf('%dnm', config.wavelengths(i));
                        if i > 1
                            axesHandles{i}.Visible = 'On';  
                        end
                    elseif i > 1
                        axesHandles{i}.Visible = 'Off';
                    end
                end

        end
        
        function clearAxis(app)
            cla(app.axes_laser1);                
            cla(app.axes_laser2);                
            cla(app.axes_laser3);                
            cla(app.axes_laser4);                
            cla(app.axes_laser5); 
            try
                cla(app.axes_laser6);                
            end

        end
        
        function Export_PlotData(app)
            outputDir = app.edit_outputDir.Value;
            date_str   = string(datetime('now', 'Format', 'yyyy_MM_dd'));

            cfg = getMicroscopeConfig(app, app.dropdown_microscope.Value);
            if isempty(cfg)
                return
            end
         
            numDichroics = numel(cfg.dichroics);
            datapoints = length(app.data(1).(cfg.wavelengthFieldNames{1}));
            dx = 100 / (datapoints - 1);
            x = 0:dx:100;
         
            for w = 1:numel(cfg.wavelengthFieldNames)
                field = cfg.wavelengthFieldNames{w};
                raw = app.data(1).(field);           % numDichroics x datapoints
         
                % Subtract the 0%-power background per dichroic, convert to mW, clip at 0
                corrected = (raw - raw(:, 1)) * 1000;
                corrected(corrected < 0) = 0;
         
                f = figure;
                ax = axes('Parent', f, 'NextPlot', 'add');
                plotArgs = {};
                for i = 1:numDichroics
                    plotArgs = [plotArgs, {x, corrected(i, :), '-o'}]; %#ok<AGROW>
                end
                plot(ax,plotArgs{:}, 'MarkerSize', 4, 'LineWidth', 2)
                set(ax, 'Fontsize', 14, 'LineWidth', 1.5, 'FontWeight', 'bold');
                title(ax,sprintf('%dnm Laser', cfg.wavelengths(w)), 'FontSize', 18, 'FontWeight', 'bold');
                ylim([0, max(corrected(:))]);
                xlabel(ax,'Set Laserpower in %', 'FontSize', 14, 'FontWeight', 'bold');
                ylabel(ax,'Measured Laserpower in mW', 'FontSize', 14, 'FontWeight', 'bold');
                lgd = legend(dichroic_list,"Location","northwest","LineWidth",1.5,)
                filename = sprintf('%s_%s_Laser_%dnm', date_str, cfg.pngFilePrefix, cfg.wavelengths(w));
                print(f, fullfile(outputDir, filename), '-dpng');
                close(f);
            end
        
        end
        
        function focusFigure(app)
            app.LaserPower.Visible = 'off';
            app.LaserPower.Visible = 'on';
        end
        
    end
    
    methods (Access = public)
        
        function config = getMicroscopeConfig(app, microscope)
                % Dichroic set is now physically identical across all microscopes.
                 dichroic_list = {'405/488/552/640', '505/594', '405/488/594'};
                switch microscope
                    case 'Confocal 1'
                        config = struct( ...
                            'displayName',           'Confocal 1', ...
                            'laserMMNames',          {{'Laser 405-50C','Laser 488-50C','Laser OBIS LS 514-150', ...
                                                        'Laser OBIS LS 552-60','Laser OBIS LS 594-100','Laser 637-140C'}}, ...
                            'wavelengthFieldNames',  {{'L405','L488','L514','L552','L594','L637'}}, ...
                            'wavelengths',           [405, 488, 514, 552, 594, 637], ...
                            'dichroics',             {dichroic_list}, ...
                            'comPort',               8, ...                           % VERIFY - see note above
                            'pngFilePrefix',         'Confocal1', ...
                            'matFilePrefix',         'Confocal1', ...
                            'setZeroPowerBeforeDichroicLoop', true, ...
                            'preLaserLoopSettleAverages',     0, ...
                            'preDichroicSettleAverages',      10, ...
                            'postDichroicSettleAverages',     0, ...
                            'preStepSettleAverages',          2, ...
                            'preStepSettlePause',             0.1, ...
                            'zeroPowerPause',                 0 ...
                        );
             
                    case 'Confocal 2'
                        config = struct( ...
                            'displayName',           'Confocal 2', ...
                            'laserMMNames',          {{'Laser 405-50C','Laser 488-150C','Laser 505-50C', ...
                                                        'Laser OBIS LS 552-60','Laser OBIS LS 594-60','Laser 640-100C'}}, ...
                            'wavelengthFieldNames',  {{'L405','L488','L505','L552','L594','L640'}}, ...
                            'wavelengths',           [405, 488, 505, 552, 594, 640], ...
                            'dichroics',             {dichroic_list}, ...
                            'comPort',               4, ...
                            'pngFilePrefix',         'new_confocal', ...               % kept identical to your original export filenames
                            'matFilePrefix',         'Confocal2', ...
                            'setZeroPowerBeforeDichroicLoop', false, ...
                            'preLaserLoopSettleAverages',     0, ...
                            'preDichroicSettleAverages',      0, ...
                            'postDichroicSettleAverages',     0, ...
                            'preStepSettleAverages',          0, ...
                            'preStepSettlePause',             0, ...
                            'zeroPowerPause',                 0 ...
                        );
             
                    case 'Confocal 3'
                        config = struct( ...
                            'displayName',           'Confocal 3', ...
                            'laserMMNames',          {{'Laser 445-75C','Laser 488-50C','Laser 514-40C', ...
                                                        'Laser OBIS LS 552-100','Laser 637-140C'}}, ...
                            'wavelengthFieldNames',  {{'L445','L488','L514','L552','L637'}}, ...
                            'wavelengths',           [445, 488, 514, 552, 637], ...
                            'dichroics',             {dichroic_list}, ...
                            'comPort',               7, ...
                            'pngFilePrefix',         'Confocal3', ...
                            'matFilePrefix',         'Confocal3', ...
                            'setZeroPowerBeforeDichroicLoop', false, ...
                            'preLaserLoopSettleAverages',     10, ...
                            'preDichroicSettleAverages',      0, ...
                            'postDichroicSettleAverages',     10, ...
                            'preStepSettleAverages',          0, ...
                            'preStepSettlePause',             0, ...
                            'zeroPowerPause',                 0 ...
                        );
             
                    case 'Confocal 4'
                        config = struct( ...
                            'displayName',           'Confocal 4', ...
                            'laserMMNames',          {{'Laser 405-50C','Laser 488-100C','Laser 514-40C', ...
                                                        'Laser OBIS LS 552-80','Laser OBIS LS 594-60','Laser 637-140C'}}, ...
                            'wavelengthFieldNames',  {{'L405','L488','L514','L552','L594','L637'}}, ...
                            'wavelengths',           [405, 488, 514, 552, 594, 637], ...
                            'dichroics',             {dichroic_list}, ...
                            'comPort',               7, ...
                            'pngFilePrefix',         'Confocal4', ...
                            'matFilePrefix',         'Confocal4', ...
                            'setZeroPowerBeforeDichroicLoop', false, ...
                            'preLaserLoopSettleAverages',     0, ...
                            'preDichroicSettleAverages',      0, ...
                            'postDichroicSettleAverages',     0, ...
                            'preStepSettleAverages',          0, ...
                            'preStepSettlePause',             0, ...
                            'zeroPowerPause',                 5 ...
                        );
             
                    otherwise
                        % Unknown / removed selection (e.g. legacy 'APOM' data) -> caller decides what to do
                        config = [];
                end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Init Micromanager to control Microscopes (Dichroics and Lasers)
            if  strcmp(getenv('COMPUTERNAME'),'BZ-WG-PDW035') % Confocal 1
                app.microscope = 'Confocal 1';
                app.dropdown_microscope.Value = 'Confocal 1';
                updateMicroscope(app); 
                app.edit_init_portPowermeter.Value = 4;
            elseif strcmp(getenv('COMPUTERNAME'),'BZ-WG-PDW036') % Confocal 2
                app.microscope = 'Confocal 2';
                app.dropdown_microscope.Value = 'Confocal 2';
                updateMicroscope(app);
                app.edit_init_portPowermeter.Value = 4;  
            elseif strcmp(getenv('COMPUTERNAME'),'BZ-WG-PDW037') % Confocal 3
                app.microscope = 'Confocal 3';
                app.dropdown_microscope.Value = 'Confocal 3';
                updateMicroscope(app);
                app.edit_init_portPowermeter.Value = 7;
            elseif strcmp(getenv('COMPUTERNAME'),'BZ-WG-PDW032') % Confocal 4
                app.microscope = 'Confocal 4';
                app.dropdown_microscope.Value = 'Confocal 4';
                updateMicroscope(app);
                app.edit_init_portPowermeter.Value = 7;
            end          

        end

        % Button pushed function: button_selectDir
        function button_selectDirPushed(app, event)
            outputDir = uigetdir('Please select a directory to save the measured power');
            if outputDir ~= 0 
                app.edit_outputDir.Value = outputDir;
            end
            focusFigure(app)
        end

        % Menu selected function: menu_about
        function menu_aboutSelected(app, event)
            uiconfirm(app.GalvoControlPanelUIFigure,'Power Measurement written by Konstantin Neuhaus (konneuhaus@gmail.com).','About','Icon','info','Options',{'OK'})

        end

        % Button pushed function: button_measurement_start
        function button_measurement_startPushed(app, event)
            tic;
            clearAxis(app);
            app.stop = 0;
         
            power_step = app.edit_measurement_stepsize.Value;
            averages   = app.edit_measurement_averages.Value;
            date_str   = string(datetime('now', 'Format', 'yyyy_MM_dd'));
         
            app.button_measurement_start.BackgroundColor = [0, 1, 0];
            app.button_measurement_start.Enable = 'Off';
            app.button_measurement_stop.BackgroundColor = [1, 0, 0];
            app.button_measurement_stop.Enable = 'On';
         
            cfg = getMicroscopeConfig(app, app.dropdown_microscope.Value);
            if isempty(cfg)
                warning('PowerMeasurement:UnknownMicroscope', ...
                    'No configuration found for "%s" - measurement aborted.', app.dropdown_microscope.Value);
                app.button_measurement_start.BackgroundColor = [0.96, 0.96, 0.96];
                app.button_measurement_start.Enable = 'On';
                app.button_measurement_stop.BackgroundColor = [0.96, 0.96, 0.96];
                app.button_measurement_stop.Enable = 'Off';
                return
            end
         
            app.text_legemd.Value = cfg.dichroics;
            datapoints   = 100 / power_step + 1;
            numLasers    = numel(cfg.wavelengthFieldNames);
            numDichroics = numel(cfg.dichroics);
         
            % Build app.data struct dynamically from the configured wavelength fields
            fieldArgs = {'Laser_Percentage_Power', []};
            for w = 1:numLasers
                fieldArgs = [fieldArgs, {cfg.wavelengthFieldNames{w}, zeros(numDichroics, datapoints)}]; %#ok<AGROW>
            end
            app.data = struct(fieldArgs{:});
            app.data(:).Laser_Percentage_Power = 0:power_step:100;
         
            if cfg.preLaserLoopSettleAverages > 0
                average_single_acquisitions(app.powermeterDevice, cfg.preLaserLoopSettleAverages);
            end
         
            for laser = 1:numLasers
                laserName = cfg.laserMMNames{laser};
         
                SetWavelength(app.powermeterDevice, cfg.wavelengths(laser));
                app.mmc.setProperty('Coherent-Scientific Remote', [laserName ' - State'], 'Off');
                app.mmc.setProperty('Coherent-Scientific Remote', [laserName ' - Modulation/Trigger'], 'CW (constant power)');
                app.mmc.setProperty('Coherent-Scientific Remote', [laserName ' - State'], 'On');
                if cfg.setZeroPowerBeforeDichroicLoop
                    app.mmc.setProperty('Coherent-Scientific Remote', [laserName ' - PowerSetpoint (%)'], 0);
                end
                pause(0.1);
         
                for dic = 1:numDichroics
                    disp(['Current Laser: ', num2str(cfg.wavelengths(laser)), 'nm  Current Dichroic: ', num2str(dic)]);
                    app.mmc.setProperty('CSUW1-Dichroic Mirror', 'State', num2str(dic - 1));
         
                    if cfg.preDichroicSettleAverages > 0
                        average_single_acquisitions(app.powermeterDevice, cfg.preDichroicSettleAverages);
                    end
         
                    m = 1;
                    for power_percentage = 0:power_step:100
                        app.mmc.setProperty('Coherent-Scientific Remote', [laserName ' - PowerSetpoint (%)'], power_percentage);
         
                        if power_percentage == 0 && cfg.zeroPowerPause > 0
                            pause(cfg.zeroPowerPause);
                        end
                        if cfg.preStepSettleAverages > 0
                            average_single_acquisitions(app.powermeterDevice, cfg.preStepSettleAverages);
                            if cfg.preStepSettlePause > 0
                                pause(cfg.preStepSettlePause);
                            end
                        end
         
                        measured_power = average_single_acquisitions(app.powermeterDevice, averages);
                        app.data(1).(cfg.wavelengthFieldNames{laser})(dic, m) = measured_power;
                        m = m + 1;
         
                        updatePlot(app, laser, dic, cfg.wavelengthFieldNames);
         
                        if app.stop
                            break
                        end
                    end
         
                    app.mmc.setProperty('Coherent-Scientific Remote', [laserName ' - PowerSetpoint (%)'], 0);
         
                    if cfg.postDichroicSettleAverages > 0
                        average_single_acquisitions(app.powermeterDevice, cfg.postDichroicSettleAverages);
                    end
         
                    if app.stop
                        break
                    end
                end
         
                app.mmc.setProperty('Coherent-Scientific Remote', [laserName ' - PowerSetpoint (%)'], 0);
                app.mmc.setProperty('Coherent-Scientific Remote', [laserName ' - State'], 'Off');
                app.mmc.setProperty('Coherent-Scientific Remote', [laserName ' - Modulation/Trigger'], 'External/Digital');
         
                if app.stop
                    break
                end
            end
         
            disp('Finished Power measurement!');
         
            data_export = app.data;
            data_export.microscope = cfg.displayName;
            data_export.stepsize = power_step;
            data_export.NumLaser = numLasers;
            data_export.averages = averages;
            data_export.laser_list_wavelengths = cfg.wavelengthFieldNames;
            data_export.dichroics_list = cfg.dichroics;
         
            save(fullfile(app.edit_outputDir.Value, ...
                sprintf('%s_laser_power_%s.mat', date_str, cfg.matFilePrefix)), 'data_export');
         
            disp('Data was saved!');
         
            app.button_measurement_start.BackgroundColor = [0.96, 0.96, 0.96];
            app.button_measurement_start.Enable = 'On';
            app.button_measurement_stop.BackgroundColor = [0.96, 0.96, 0.96];
            app.button_measurement_stop.Enable = 'Off';
         
            t = toc;
            disp(['Measurement took: ', num2str(t), ' s']);

        end

        % Button pushed function: button_init_powermeter
        function button_init_powermeterPushed(app, event)
            port = app.edit_init_portPowermeter.Value;
            port_ = ['COM',num2str(port)];
            pm = Open_powermeter(powermeter,port_);
            app.powermeterDevice = pm;

%             warning('on', 'MATLAB:serial:fgetl:unsuccessfulRead');
            test_measurement = average_single_acquisitions(app.powermeterDevice,1);
            if isnan(test_measurement)
                Close(app.powermeterDevice);
            else
                app.button_init_powermeter.BackgroundColor = [0,1,0];
                app.button_init_powermeter.Enable = 'off';
                disp(['Test power measurement: ',num2str(test_measurement)]);
                app.button_init_closePowermeter.Enable = 'on';
            end

        end

        % Menu selected function: menu_debug
        function menu_debugSelected(app, event)
            disp('Debug pressed')
            assignin('base', 'app', app)
            app.button_measurement_start.BackgroundColor = [0.96,0.96,0.96];
            app.button_measurement_start.Enable = 'On';
            app.button_measurement_stop.BackgroundColor = [0.96,0.96,0.96];
            app.button_measurement_stop.Enable = 'Off';            
        end

        % Value changed function: dropdown_microscope
        function dropdown_microscopeValueChanged(app, event)
            updateMicroscope(app);
        end

        % Button pushed function: button_measurement_stop
        function button_measurement_stopPushed(app, event)
            app.stop = 1;
        end

        % Button pushed function: button_init_micromanager
        function button_init_micromanagerPushed(app, event)
            app.MM.gui = org.micromanager.internal.MMStudio(false);
            
            try
                app.MM.mmc = app.MM.gui.getCore;
            catch
                app.MM.mmc = app.MM.gui.getCMMCore;
            end
            app.MM.acq = app.MM.gui.getAcquisitionEngine;
            disp('Micro-Manager started.');
            app.mmc = app.MM.mmc;
            app.button_init_micromanager.BackgroundColor = [0,1,0];
            app.button_init_micromanager.Enable = 'Off';
        end

        % Menu selected function: menu_import
        function menu_importSelected(app, event)
            [FileName,PathName,FilterIndex] = uigetfile('.mat','Please select power measurement');
            if FileName ~= 0 
                app.data = load(fullfile(PathName,FileName),'data_export');
                load(fullfile(PathName,FileName),'data_export');
                app.edit_outputDir.Value = PathName;
            end
            focusFigure(app)
            app.data = data_export;
            app.text_legemd.Value = data_export.dichroics_list;
            app.data(:).Laser_Percentage_Power = 0:data_export.stepsize:100;
            app.microscope = data_export.microscope;
            app.dropdown_microscope.Value = data_export.microscope;
            updateMicroscope(app)
            for laser = 1:data_export.NumLaser
                for d = 1:3
                     updatePlot(app,laser,d,data_export.laser_list_wavelengths)     
                end 
            end
            
            
        end

        % Menu selected function: menu_export_plot
        function menu_export_plotSelected(app, event)
            Export_PlotData(app)
        end

        % Button pushed function: button_init_closePowermeter
        function button_init_closePowermeterPushed(app, event)
            Close(app.powermeterDevice);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LaserPower = uifigure('Visible', 'off');
            app.LaserPower.Name = 'LaserPower';
            app.Menu = uimenu(app.LaserPower);
            app.Menu.Text = 'Menu';
            app.menu_about = uimenu(app.Menu);
            app.menu_about.MenuSelectedFcn = createCallbackFcn(app, @menu_aboutSelected, true);
            app.menu_about.Text = 'About';
            app.menu_debug = uimenu(app.Menu);
            app.menu_debug.MenuSelectedFcn = createCallbackFcn(app, @menu_debugSelected, true);
            app.menu_debug.Separator = 'on';
            app.menu_debug.Text = 'Debug';
            app.menu_import = uimenu(app.LaserPower);
            app.menu_import.MenuSelectedFcn = createCallbackFcn(app, @menu_importSelected, true);
            app.menu_import.Text = 'Import';
            app.menu_export = uimenu(app.LaserPower);
            app.menu_export.Text = 'Export';
            app.menu_export_plot = uimenu(app.menu_export);
            app.menu_export_plot.MenuSelectedFcn = createCallbackFcn(app, @menu_export_plotSelected, true);
            app.menu_export_plot.Text = 'Plot Data';
            app.axes_laser6 = uiaxes(app.LaserPower);
            title(app.axes_laser6, '405')
            xlabel(app.axes_laser6, 'Laserpower [%]')
            ylabel(app.axes_laser6, 'Power [mW]')
            app.axes_laser5 = uiaxes(app.LaserPower);
            title(app.axes_laser5, '640')
            xlabel(app.axes_laser5, 'Laserpower [%]')
            ylabel(app.axes_laser5, 'Power [mW]')
            app.axes_laser4 = uiaxes(app.LaserPower);
            title(app.axes_laser4, '594nm')
            xlabel(app.axes_laser4, 'Laserpower [%]')
            ylabel(app.axes_laser4, 'Power [mW]')
            app.axes_laser3 = uiaxes(app.LaserPower);
            title(app.axes_laser3, '552nm')
            xlabel(app.axes_laser3, 'Laserpower [%]')
            ylabel(app.axes_laser3, 'Power [mW]')
            app.axes_laser2 = uiaxes(app.LaserPower);
            title(app.axes_laser2, '505/514nm')
            xlabel(app.axes_laser2, 'Laserpower [%]')
            ylabel(app.axes_laser2, 'Power [mW]')
            app.axes_laser1 = uiaxes(app.LaserPower);
            title(app.axes_laser1, '488nm')
            xlabel(app.axes_laser1, 'Laserpower [%]')
            ylabel(app.axes_laser1, 'Power [mW]')
            app.OutputDirectoryEditFieldLabel = uilabel(app.LaserPower);
            app.OutputDirectoryEditFieldLabel.Text = 'Output Directory';
            app.edit_outputDir = uieditfield(app.LaserPower, 'text');
            app.MicroscopeDropDownLabel = uilabel(app.LaserPower);
            app.MicroscopeDropDownLabel.Text = 'Microscope';
            app.dropdown_microscope = uidropdown(app.LaserPower);
            app.dropdown_microscope.Items = {'Confocal 1', 'Confocal 2', 'Confocal 3', 'Confocal 4'};
            app.dropdown_microscope.ValueChangedFcn = createCallbackFcn(app, @dropdown_microscopeValueChanged, true);
            app.dropdown_microscope.Value = 'Confocal 1';
            app.StepsizeEditFieldLabel = uilabel(app.LaserPower);
            app.StepsizeEditFieldLabel.Text = 'Stepsize [%]';
            app.edit_measurement_stepsize = uieditfield(app.LaserPower, 'numeric');
            app.edit_measurement_stepsize.LowerLimitInclusive = 'off';
            app.edit_measurement_stepsize.Limits = [0 100];
            app.edit_measurement_stepsize.Value = 1;
            app.COMPortPowermeterEditFieldLabel = uilabel(app.LaserPower);
            app.COMPortPowermeterEditFieldLabel.Text = 'COM Port Powermeter';
            app.edit_init_portPowermeter = uieditfield(app.LaserPower, 'numeric');
            app.edit_init_portPowermeter.Value = 7;
            app.button_init_powermeter = uibutton(app.LaserPower, 'push');
            app.button_init_powermeter.ButtonPushedFcn = createCallbackFcn(app, @button_init_powermeterPushed, true);
            app.button_init_powermeter.Text = 'Init Powermeter';
            app.button_selectDir = uibutton(app.LaserPower, 'push');
            app.button_selectDir.ButtonPushedFcn = createCallbackFcn(app, @button_selectDirPushed, true);
            app.button_selectDir.Text = 'Select Output Directory';
            app.button_measurement_start = uibutton(app.LaserPower, 'push');
            app.button_measurement_start.ButtonPushedFcn = createCallbackFcn(app, @button_measurement_startPushed, true);
            app.button_measurement_start.Text = 'Start Measurement';
            app.button_measurement_stop = uibutton(app.LaserPower, 'push');
            app.button_measurement_stop.ButtonPushedFcn = createCallbackFcn(app, @button_measurement_stopPushed, true);
            app.button_measurement_stop.Text = 'Stop Measurement';
            app.AveragesEditFieldLabel = uilabel(app.LaserPower);
            app.AveragesEditFieldLabel.Text = 'Averages';
            app.edit_measurement_averages = uieditfield(app.LaserPower, 'numeric');
            app.edit_measurement_averages.LowerLimitInclusive = 'off';
            app.edit_measurement_averages.Limits = [0 100];
            app.edit_measurement_averages.RoundFractionalValues = 'on';
            app.edit_measurement_averages.Value = 5;
            app.LegendTextAreaLabel = uilabel(app.LaserPower);
            app.LegendTextAreaLabel.Text = 'Legend';
            app.text_legemd = uitextarea(app.LaserPower);
            app.button_init_micromanager = uibutton(app.LaserPower, 'push');
            app.button_init_micromanager.ButtonPushedFcn = createCallbackFcn(app, @button_init_micromanagerPushed, true);
            app.button_init_micromanager.Text = 'Init Micromanager';
            app.button_init_closePowermeter = uibutton(app.LaserPower, 'push');
            app.button_init_closePowermeter.ButtonPushedFcn = createCallbackFcn(app, @button_init_closePowermeterPushed, true);
            app.button_init_closePowermeter.Enable = 'off';
            app.button_init_closePowermeter.Text = 'Close Powermeter';
            % Show the figure after all components are created
            app.LaserPower.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowerMeasurement

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LaserPower)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.LaserPower)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LaserPower)
        end
    end
end